classdef ManageAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        DoneButton                matlab.ui.control.Button
        GridLayout2               matlab.ui.container.GridLayout
        GridLayout4               matlab.ui.container.GridLayout
        CreateAccountPanel        matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        CreateAccountButton       matlab.ui.control.Button
        NameEditField_4           matlab.ui.control.EditField
        Button_4                  matlab.ui.control.Button
        DropDown_3                matlab.ui.control.DropDown
        Label_2                   matlab.ui.control.Label
        NameEditField_3           matlab.ui.control.EditField
        Button_3                  matlab.ui.control.Button
        DropDown_2                matlab.ui.control.DropDown
        TierDropDown_2            matlab.ui.control.DropDown
        NameEditField_2           matlab.ui.control.EditField
        PasswordEditField_2       matlab.ui.control.EditField
        IDEditField_2             matlab.ui.control.EditField
        ResetPasswordButton       matlab.ui.control.Button
        InformationPanel          matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        ApplyChangesButton        matlab.ui.control.Button
        PhoneEditField            matlab.ui.control.EditField
        PhoneEditFieldLabel       matlab.ui.control.Label
        Button_2                  matlab.ui.control.Button
        DropDown                  matlab.ui.control.DropDown
        Label                     matlab.ui.control.Label
        EmailEditField            matlab.ui.control.EditField
        EmailEditFieldLabel       matlab.ui.control.Label
        Button                    matlab.ui.control.Button
        AffiliationDropDown       matlab.ui.control.DropDown
        AffiliationDropDownLabel  matlab.ui.control.Label
        TierDropDown              matlab.ui.control.DropDown
        TierDropDownLabel         matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        IDEditField               matlab.ui.control.EditField
        IDEditFieldLabel          matlab.ui.control.Label
        GridLayout3               matlab.ui.container.GridLayout
        DeleteButton              matlab.ui.control.Button
        ListBox                   matlab.ui.control.ListBox
    end

    
    properties (Access = private)        
        accounts;
        orgAccounts;

        TIER_ADMIN_STRING = 'Admin';

        ACCOUNT_FORM_TYPE_AFFILIATION = 'Affiliation';
        ACCOUNT_FORM_TYPE_MAILDOMAIN = 'EmailDomain';
        ACCOUNT_FILENAME = 'accounts.mat';
        ACCOUNT_FORMS_FILENAME = 'account_forms.mat';
        ACCOUNT_FORMS_VARIABLENAME = 'AccountForms';
    end    
    
    methods (Access = private)
        
        function InitialLoadAccountList(app)  
            if ~isfile(app.ACCOUNT_FILENAME)                
                disp('Account information is missing or corrupted. Contact the manufacturer.')
                return;
            end

            loadedVar = load(app.ACCOUNT_FILENAME);
            app.orgAccounts = loadedVar.Accounts;
            app.accounts = app.orgAccounts;
            RenewAccountList(app);
        end

        function RenewAccountList(app)
            app.ListBox.Items = fieldnames(app.accounts);
        end

    end
    
    methods (Access = public)
        
        function UpdateAccountForms(app)            
            ManageAccountFormAction('Check', '', '', app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
            tmp = load(app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
            app.AffiliationDropDown.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).Affiliation;
            app.DropDown.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).EmailDomain;
            app.DropDown_2.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).Affiliation;
            app.DropDown_3.Items = tmp.(app.ACCOUNT_FORMS_VARIABLENAME).EmailDomain;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            InitialLoadAccountList(app);      
            UpdateAccountForms(app);
            ListBoxValueChanged(app, []);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.IDEditField.Value = app.ListBox.Value;
            app.NameEditField.Value = app.accounts.(app.ListBox.Value).Name;
            app.TierDropDown.Value = app.accounts.(app.ListBox.Value).Tier;
            app.AffiliationDropDown.Value = app.accounts.(app.ListBox.Value).Affiliation;
            app.EmailEditField.Value = app.accounts.(app.ListBox.Value).EmailName;
            app.DropDown.Value = app.accounts.(app.ListBox.Value).EmailDomain;
            app.PhoneEditField.Value = app.accounts.(app.ListBox.Value).PhoneNumber;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            id = app.ListBox.Value;
            tmpAccounts = rmfield(app.accounts, id);
            fieldNames = fieldnames(tmpAccounts);            
            numAdmin = 0;
            for i = 1:length(fieldNames)
                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);                
            end
            
            if ~numAdmin
                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Delete error');
                return;
            end

            app.accounts = tmpAccounts;            
            RenewAccountList(app);
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            % TODO : Change name field
            % Affiliation, Email, Phone
            tmpAccounts = app.accounts;
            prevId = app.ListBox.Value;
            newId = app.IDEditField.Value;
            newName = app.NameEditField.Value;
            [isValid, stateMsg] = ValidateId(newId);
            if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end

            [isValid, stateMsg] = ValidateName(newName);
            if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end

            %             [isValid, stateMsg] = Validate(newName);
            %             if ~isValid; uialert(app.UIFigure, stateMsg, 'Renaming error'); return; end

            tmpAccounts.(prevId).Tier = app.TierDropDown.Value;
            fieldNames = fieldnames(tmpAccounts);
            numAdmin = 0;
            for i = 1:length(fieldNames)
                numAdmin = numAdmin + strcmp(tmpAccounts.(fieldNames{i}).Tier, app.TIER_ADMIN_STRING);
            end

            if ~numAdmin
                uialert(app.UIFigure,...
                    'Admin tier shoud be exist at least 1 account',...
                    'Tier changing error');
                return;
            end

            app.accounts = renameStructField(app.accounts, prevId, newId);
            app.accounts.(newId).Tier = app.TierDropDown.Value;
            app.accounts.(newId).Name = newName;
            RenewAccountList(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isequal(app.accounts, app.orgAccounts)
                delete(app);
                return; 
            end
            
            selection = uiconfirm(app.UIFigure, 'Apply changes?', 'Confirmation', ...
                'Options', {'OK', 'No', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 3);
            if strcmp(selection, 'Cancel'); return; end
            
            if strcmp(selection, 'No'); delete(app); return; end

            Accounts = app.accounts;
            save(app.ACCOUNT_FILENAME, "Accounts");
            delete(app)
        end

        % Button pushed function: ResetPasswordButton
        function ResetPasswordButtonPushed(app, event)
            PASSWORD_LENGTH = 16;
            id = app.ListBox.Value;
            msg = sprintf('Are you sure you want to reset password for account "%s"?', id);
            selection = uiconfirm(app.UIFigure, msg, 'Confirmation', ...
                'Options', {'OK', 'Cancel'}, ...
                'DefaultOption', 1, 'CancelOption', 2, ...
                'Icon', 'warning');
            if strcmp(selection, 'Cancel'); return; end

            password = GenerateRandomPassword(PASSWORD_LENGTH);
            clipboard('copy', password);
            hashedPassword = StringToHashedHex(password);
            app.accounts.(id).Password = hashedPassword;
            msg = sprintf('New Password\n\n    %s\n\nis copied to Clipboard', password);
            uiconfirm(app.UIFigure, msg, 'Confirm', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');
        end

        % Button pushed function: CreateAccountButton
        function CreateAccountButtonPushed(app, event)
            id = app.IDEditField_2.Value;
            password = app.PasswordEditField_2.Value;
            name = app.NameEditField_2.Value;
            tier = app.TierDropDown_2.Value;
            affiliation = app.DropDown_2.Value;
            emailName = app.NameEditField_3.Value;
            emailDomain = app.DropDown_3.Value;
            phoneNumber = app.NameEditField_4.Value;

            [isGenerated, stateMsg, newAccountStruct] =...
                GenerateAccount(id, password, name, tier, affiliation, ...
                emailName, emailDomain, phoneNumber, app.accounts);
            if ~isGenerated; uialert(app.UIFigure, stateMsg, 'Create account error'); return; end
            
            app.accounts = newAccountStruct;
            RenewAccountList(app);
            app.IDEditField_2.Value = '';
            app.PasswordEditField_2.Value = '';
            app.NameEditField_2.Value = '';
            app.TierDropDown_2.Value = app.TierDropDown_2.Items{1};
            app.DropDown_2.Value = app.DropDown_2.Items{1};
            app.NameEditField_3.Value = '';
            app.DropDown_3.Value = app.DropDown_3.Items{1};
            app.NameEditField_4.Value = '';
            msg = sprintf('The account "%s" has been successfully created.', id);
            uiconfirm(app.UIFigure, msg, 'Completed', ...
                'Options', {'OK'}, ...
                'DefaultOption', 1, ...
                'Icon', 'success');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            UIFigureCloseRequest(app, []);
        end

        % Button pushed function: Button, Button_3
        function ButtonPushed(app, event)
            ManageAccountForm(app, app.ACCOUNT_FORM_TYPE_AFFILIATION, app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
        end

        % Button pushed function: Button_2, Button_4
        function Button_2Pushed(app, event)
            ManageAccountForm(app, app.ACCOUNT_FORM_TYPE_MAILDOMAIN, app.ACCOUNT_FORMS_FILENAME, app.ACCOUNT_FORMS_VARIABLENAME);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.ListBox = uilistbox(app.GridLayout3);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.DeleteButton = uibutton(app.GridLayout3, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.InformationPanel = uipanel(app.GridLayout4);
            app.InformationPanel.Title = 'Information';
            app.GridLayout5 = uigridlayout(app.InformationPanel);
            app.IDEditFieldLabel = uilabel(app.GridLayout5);
            app.IDEditFieldLabel.Text = 'ID :';
            app.IDEditField = uieditfield(app.GridLayout5, 'text');
            app.NameEditFieldLabel = uilabel(app.GridLayout5);
            app.NameEditFieldLabel.Text = 'Name :';
            app.NameEditField = uieditfield(app.GridLayout5, 'text');
            app.TierDropDownLabel = uilabel(app.GridLayout5);
            app.TierDropDownLabel.Text = 'Tier :';
            app.TierDropDown = uidropdown(app.GridLayout5);
            app.TierDropDown.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown.Value = 'Admin';
            app.AffiliationDropDownLabel = uilabel(app.GridLayout5);
            app.AffiliationDropDownLabel.Text = 'Affiliation :';
            app.AffiliationDropDown = uidropdown(app.GridLayout5);
            app.AffiliationDropDown.Items = {};
            app.AffiliationDropDown.Value = {};
            app.Button = uibutton(app.GridLayout5, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.Button.Text = '';
            app.EmailEditFieldLabel = uilabel(app.GridLayout5);
            app.EmailEditFieldLabel.Text = 'E-mail:';
            app.EmailEditField = uieditfield(app.GridLayout5, 'text');
            app.Label = uilabel(app.GridLayout5);
            app.Label.Text = '@';
            app.DropDown = uidropdown(app.GridLayout5);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.Button_2.Text = '';
            app.PhoneEditFieldLabel = uilabel(app.GridLayout5);
            app.PhoneEditFieldLabel.Text = 'Phone :';
            app.PhoneEditField = uieditfield(app.GridLayout5, 'text');
            app.ApplyChangesButton = uibutton(app.GridLayout5, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.ResetPasswordButton = uibutton(app.GridLayout4, 'push');
            app.ResetPasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPasswordButtonPushed, true);
            app.ResetPasswordButton.Text = 'Reset Password';
            app.CreateAccountPanel = uipanel(app.GridLayout4);
            app.CreateAccountPanel.Title = 'Create Account';
            app.GridLayout6 = uigridlayout(app.CreateAccountPanel);
            app.IDEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.IDEditField_2.Placeholder = 'ID';
            app.PasswordEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.PasswordEditField_2.Placeholder = 'Password';
            app.NameEditField_2 = uieditfield(app.GridLayout6, 'text');
            app.NameEditField_2.Placeholder = 'Name';
            app.TierDropDown_2 = uidropdown(app.GridLayout6);
            app.TierDropDown_2.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown_2.Placeholder = 'Select a Tier';
            app.TierDropDown_2.Value = 'Staff';
            app.DropDown_2 = uidropdown(app.GridLayout6);
            app.DropDown_2.Items = {};
            app.DropDown_2.Value = {};
            app.Button_3 = uibutton(app.GridLayout6, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.Button_3.Text = '';
            app.NameEditField_3 = uieditfield(app.GridLayout6, 'text');
            app.NameEditField_3.Placeholder = 'E-mail';
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Text = '@';
            app.DropDown_3 = uidropdown(app.GridLayout6);
            app.DropDown_3.Items = {};
            app.DropDown_3.Value = {};
            app.Button_4 = uibutton(app.GridLayout6, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'setting.png');
            app.Button_4.Text = '';
            app.NameEditField_4 = uieditfield(app.GridLayout6, 'text');
            app.NameEditField_4.Placeholder = 'Phone number';
            app.CreateAccountButton = uibutton(app.GridLayout6, 'push');
            app.CreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccountButtonPushed, true);
            app.CreateAccountButton.Text = 'Create Account';
            app.GridLayout7 = uigridlayout(app.GridLayout);
            app.DoneButton = uibutton(app.GridLayout7, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccount

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end