classdef ManageAccountForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        DeleteButton  matlab.ui.control.Button
        EditField_2   matlab.ui.control.EditField
        RenameButton  matlab.ui.control.Button
        DoneButton    matlab.ui.control.Button
        AddButton     matlab.ui.control.Button
        EditField     matlab.ui.control.EditField
        ListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)

        AccountFormType;
        AccountFormFileName;
        AccountFormVariableName;
        OrgForms;        

    end
    
    properties (Access = public)

        ParentApp;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, accountFormType, accountFormFileName, accountFormVariableName)
            
            app.ParentApp = parentApp;
            app.AccountFormType = accountFormType;
            app.AccountFormFileName = accountFormFileName;
            app.AccountFormVariableName = accountFormVariableName;
            app.UIFigure.Name = sprintf('Manage %ss', app.AccountFormType);
            [isValidFormFile, msg] = ManageAccountFormAction('Check', '', '', app.AccountFormFileName, app.AccountFormVariableName);

            if ~isValidFormFile

                disp('Check account_forms.mat file!');
                disp(msg);

                return;
            end

            app.OrgForms = load(app.AccountFormFileName);            
            app.ListBox.Items = app.OrgForms.(app.AccountFormVariableName).(app.AccountFormType);

            ListBoxValueChanged(app, []);

        end

        % Button pushed function: RenameButton
        function RenameButtonPushed(app, event)
            
            if isempty(app.EditField_2.Value)

                return;

            end

            [isValid, stateMsg] = ValidateInput(app.AccountFormType, app.EditField_2.Value, '');

            if ~isValid

                title = 'Input validation';

                uialert(app.UIFigure, stateMsg, title);

                return;

            end

            if sum(strcmpi(app.ListBox.Items, app.EditField_2.Value))

                msg = 'Input exists in the existing list';
                title = 'Input validation';
                uialert(app.UIFigure, msg, title);

                return;

            end

            app.ListBox.Items{strcmp(app.ListBox.Items, app.ListBox.Value)} = app.EditField_2.Value;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            if isempty(app.EditField.Value)

                return;

            end

            [isValid, msg] = ValidateInput(app.AccountFormType, app.EditField.Value, '');

            if ~isValid

                msg = 'Invalid Input';
                title = 'Input validation';
                uialert(app.UIFigure, msg, title);

                return;

            end

            if sum(strcmpi(app.ListBox.Items, app.EditField.Value))

                msg = 'Input exists in the existing list';
                title = 'Input validation';
                uialert(app.UIFigure, msg, title);

                return;

            end

            app.ListBox.Items{end+1} = app.EditField.Value;

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            
            title = 'Delete Error';
            
            if isempty(app.ListBox.Items) || isempty(app.ListBox.Value)

                return;

            end

            if length(app.ListBox.Items) == 1
                
                uialert(app.UIFigure, 'At least one item should be exist', title);

                return;

            end

            ids = fieldnames(app.ParentApp.Accounts);
            targetFieldName = sprintf('%sIndex', app.AccountFormType);
            targetIndex = find(strcmp(app.ListBox.Items, app.ListBox.Value));
            
            selectedUsers = '';

            for i = 1:length(ids)

                tmp = (app.ParentApp.Accounts.(ids{1}).(targetFieldName) == targetIndex);

                if tmp

                    selectedUsers{end+1} = ids{i};

                end

            end

            if ~isempty(selectedUsers)

                msg = sprintf('Cannot delete a item.\n %d user(s) are using the item. \n(%s)', length(selectedUsers), strjoin(selectedUsers, ', '));
                uialert(app.UIFigure, msg, title);

                return;

            end
            
            app.ListBox.Items = app.ListBox.Items(~strcmp(app.ListBox.Items, app.ListBox.Value));

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
            UIFigureCloseRequest(app, []);

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            app.EditField_2.Value = app.ListBox.Value;

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if ~isequal(app.ListBox.Items(:), app.OrgForms.(app.AccountFormVariableName).(app.AccountFormType)(:))

                msg = 'Save changes?';
                title = 'Confirm Save';
                selection = uiconfirm(app.UIFigure, msg, title, ...
                    'Options', {'Yes', 'No', 'Cancel'}, ...
                    'DefaultOption', 1, 'CancelOption', 3);

                switch selection

                    case 'Yes'

                        newForms = app.OrgForms;
                        newForms.(app.AccountFormVariableName).(app.AccountFormType) = app.ListBox.Items(:);
                        save(app.AccountFormFileName, '-struct', 'newForms', app.AccountFormVariableName);
                        UpdateAccountForms(app.ParentApp);

                    case 'Cancel'

                        return;

                end
            end

            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ListBox = uilistbox(app.GridLayout);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Placeholder = 'Add item';
            app.AddButton = uibutton(app.GridLayout, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.RenameButton = uibutton(app.GridLayout, 'push');
            app.RenameButton.ButtonPushedFcn = createCallbackFcn(app, @RenameButtonPushed, true);
            app.RenameButton.Text = 'Rename';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccountForm(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end