classdef ManageAuthority < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        ResetButton                 matlab.ui.control.Button
        EditDropDown                matlab.ui.control.DropDown
        EditDropDownLabel           matlab.ui.control.Label
        AccessDropDown              matlab.ui.control.DropDown
        AccessDropDownLabel         matlab.ui.control.Label
        CurrentUserLabel            matlab.ui.control.Label
        AnalysisCheckBox            matlab.ui.control.CheckBox
        SaveSPRImagesCheckBox       matlab.ui.control.CheckBox
        AutosamplerSettingCheckBox  matlab.ui.control.CheckBox
        ManageAudittrailCheckBox    matlab.ui.control.CheckBox
        DoneButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)

        ParentApp;                

    end
    
    properties (Access = private)

        currentAccount;
        currentId;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ParentApp)
            
            app.ParentApp = ParentApp;
            app.currentId = ParentApp.ListBox.Value;
            app.currentAccount = app.ParentApp.Accounts.(app.currentId);
            app.CurrentUserLabel.Text = ...
                sprintf('%s (%s, %s)', app.currentId, ...
                app.ParentApp.Accounts.(app.currentId).Name, ...
                app.ParentApp.Accounts.(app.currentId).Tier);

            ResetButtonPushed(app, []);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            app.AnalysisCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Accesible;
            app.SaveSPRImagesCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth2.Accesible;
            app.AutosamplerSettingCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth3.Accesible;
            app.ManageAudittrailCheckBox.Value = app.ParentApp.Accounts.(app.currentId).Authority.Auth4.Accesible;

            app.AccessDropDown.Value = app.AccessDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Option1};
            app.EditDropDown.Value = app.EditDropDown.Items{app.ParentApp.Accounts.(app.currentId).Authority.Auth1.Option2};

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            
           UIFigureCloseRequest(app, event);

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             
            app.currentAccount.Authority.Auth1.Accesible = app.AnalysisCheckBox.Value;
            app.currentAccount.Authority.Auth2.Accesible = app.SaveSPRImagesCheckBox.Value;
            app.currentAccount.Authority.Auth3.Accesible = app.AutosamplerSettingCheckBox.Value;
            app.currentAccount.Authority.Auth4.Accesible = app.ManageAudittrailCheckBox.Value;
            
            app.currentAccount.Authority.Auth1.Option1 = find(strcmp(app.AccessDropDown.Items, app.AccessDropDown.Value));
            app.currentAccount.Authority.Auth1.Option2 = find(strcmp(app.EditDropDown.Items, app.EditDropDown.Value));
            
            if isequal(app.ParentApp.Accounts.(app.currentId), app.currentAccount)

               delete(app);

               return;

            end
            
            select = uiconfirm(app.UIFigure,...
                'Save Changes?', 'Confirmation',...
                'Options', {'OK', 'No', 'Cancel'},...
                'DefaultOption', 1, 'CancelOption', 3);

            if strcmp(select, 'Cancel')

                return;

            end

            if strcmp(select, 'OK')

                app.ParentApp.Accounts.(app.currentId) = app.currentAccount;

            end            
            
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Manage authority';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.ManageAudittrailCheckBox = uicheckbox(app.GridLayout);
            app.ManageAudittrailCheckBox.Text = 'Manage Audit-trail';
            app.AutosamplerSettingCheckBox = uicheckbox(app.GridLayout);
            app.AutosamplerSettingCheckBox.Text = 'Autosampler Setting';
            app.SaveSPRImagesCheckBox = uicheckbox(app.GridLayout);
            app.SaveSPRImagesCheckBox.Text = 'Save SPR Images';
            app.AnalysisCheckBox = uicheckbox(app.GridLayout);
            app.AnalysisCheckBox.Text = 'Analysis';
            app.CurrentUserLabel = uilabel(app.GridLayout);
            app.CurrentUserLabel.Text = 'cdseo (Manager)';
            app.AccessDropDownLabel = uilabel(app.GridLayout);
            app.AccessDropDownLabel.Text = 'Access :';
            app.AccessDropDown = uidropdown(app.GridLayout);
            app.AccessDropDown.Items = {'Deny', 'Own Data', 'Team Data', 'All Data'};
            app.AccessDropDown.Value = 'Deny';
            app.EditDropDownLabel = uilabel(app.GridLayout);
            app.EditDropDownLabel.Text = 'Edit :';
            app.EditDropDown = uidropdown(app.GridLayout);
            app.EditDropDown.Items = {'Deny', 'Own Data', 'Team Data', 'All Data'};
            app.EditDropDown.Value = 'Deny';
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAuthority(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end